classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LetterTextAreaLabel        matlab.ui.control.Label
        LetterTextArea             matlab.ui.control.TextArea
        PercentTextAreaLabel       matlab.ui.control.Label
        PercentTextArea            matlab.ui.control.TextArea
        CalculateButton            matlab.ui.control.Button
        INPUTSPanel                matlab.ui.container.Panel
        ASSIGNMENT5EditField       matlab.ui.control.EditField
        ASSIGNMENT5EditFieldLabel  matlab.ui.control.Label
        ASSIGNMENT4EditField       matlab.ui.control.EditField
        ASSIGNMENT4EditFieldLabel  matlab.ui.control.Label
        ASSIGNMENT3EditField       matlab.ui.control.EditField
        ASSIGNMENT3EditFieldLabel  matlab.ui.control.Label
        ASSIGNMENT2EditField       matlab.ui.control.EditField
        ASSIGNMENT2EditFieldLabel  matlab.ui.control.Label
        ASSIGNMENT1EditField       matlab.ui.control.EditField
        ASSIGNMENT1EditFieldLabel  matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        Label                      matlab.ui.control.Label
    end

    
    properties (Access = private)
       
        highest = 5 % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
           
            percv = str2double(app.ASSIGNMENT1EditField.Value)+ str2double(app.ASSIGNMENT2EditField.Value)+...
                str2double(app.ASSIGNMENT3EditField.Value)+ str2double(app.ASSIGNMENT4EditField.Value)+...
                str2double(app.ASSIGNMENT5EditField.Value);% Description
             percs = num2str(percv/5);

              app.PercentTextArea.Value = percs;
    
          percd = str2double(percs)  
         
            if percd >= 90 
    letter = 'A';
elseif (percd>= 80)
    letter = 'B';
elseif (percd>=70)
    letter = 'C';
elseif (percd>= 60)
    letter = 'D';
            elseif (percd<60) 
                letter = 'F';
    
            end
               app.LetterTextArea.Value = letter;
              
disp(letter)
   
    disp(percs)
    disp(app.ASSIGNMENT1EditField.Value)
    
whos p

               
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Grade Calclulator'};
            app.INPUTSPanel = uipanel(app.UIFigure);
            app.INPUTSPanel.Title = 'INPUTS';
            app.ASSIGNMENT1EditFieldLabel = uilabel(app.INPUTSPanel);
            app.ASSIGNMENT1EditFieldLabel.Text = 'ASSIGNMENT1';
            app.ASSIGNMENT1EditField = uieditfield(app.INPUTSPanel, 'text');
            app.ASSIGNMENT2EditFieldLabel = uilabel(app.INPUTSPanel);
            app.ASSIGNMENT2EditFieldLabel.Text = 'ASSIGNMENT2';
            app.ASSIGNMENT2EditField = uieditfield(app.INPUTSPanel, 'text');
            app.ASSIGNMENT3EditFieldLabel = uilabel(app.INPUTSPanel);
            app.ASSIGNMENT3EditFieldLabel.Text = 'ASSIGNMENT3';
            app.ASSIGNMENT3EditField = uieditfield(app.INPUTSPanel, 'text');
            app.ASSIGNMENT4EditFieldLabel = uilabel(app.INPUTSPanel);
            app.ASSIGNMENT4EditFieldLabel.Text = 'ASSIGNMENT4';
            app.ASSIGNMENT4EditField = uieditfield(app.INPUTSPanel, 'text');
            app.ASSIGNMENT5EditFieldLabel = uilabel(app.INPUTSPanel);
            app.ASSIGNMENT5EditFieldLabel.Text = 'ASSIGNMENT5';
            app.ASSIGNMENT5EditField = uieditfield(app.INPUTSPanel, 'text');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.PercentTextArea = uitextarea(app.UIFigure);
            app.PercentTextAreaLabel = uilabel(app.UIFigure);
            app.PercentTextAreaLabel.Text = 'Percent';
            app.LetterTextArea = uitextarea(app.UIFigure);
            app.LetterTextAreaLabel = uilabel(app.UIFigure);
            app.LetterTextAreaLabel.Text = 'Letter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end